classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        Menu        matlab.ui.container.Menu
        Menu_3      matlab.ui.container.Menu
        CH1Menu     matlab.ui.container.Menu
        CH2Menu     matlab.ui.container.Menu
        Menu_4      matlab.ui.container.Menu
        Menu_5      matlab.ui.container.Menu
        Menu_6      matlab.ui.container.Menu
        Menu_2      matlab.ui.container.Menu
        Menu_7      matlab.ui.container.Menu
        Menu_9      matlab.ui.container.Menu
        CH1Menu_4   matlab.ui.container.Menu
        CH2Menu_4   matlab.ui.container.Menu
        MATLABMenu  matlab.ui.container.Menu
        CH1Menu_5   matlab.ui.container.Menu
        CH2Menu_5   matlab.ui.container.Menu
        Menu_8      matlab.ui.container.Menu
        Menu_10     matlab.ui.container.Menu
        Menu_11     matlab.ui.container.Menu
        FFTMenu     matlab.ui.container.Menu
        CH1Menu_6   matlab.ui.container.Menu
        CH2Menu_6   matlab.ui.container.Menu
        Spinner     matlab.ui.control.Spinner
        Label_3     matlab.ui.control.Label
        Label_2     matlab.ui.control.Label
        Label       matlab.ui.control.Label
        UIAxes2     matlab.ui.control.UIAxes
        UIAxes      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        generate_signal_app
        conv_app
        signal_synthesis_app
        signature_app
    end
    
    properties (Access = public)
        %用于产生信号
        signal_type=1
        channel=1
        T=1
        %用于信号合成
        channell=1
        method=1
        %用于卷积
        N1=0.5
        N2=0.5
        N3=0
        channelll=1
        %用于信号特征
        f=0 %频率
        TT=0 %周期
        peak=0 %峰值
        p2p=0 %峰峰值
        mean=0 %平均值
        rmsd=0 %均方差
        var=0 %方差
        rms=0 %rms

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: Menu_5
        function Menu_5Selected(app, event)
            app.Menu_5.Enable="off";
            app.enerate_signal_app=generate_signal(app);
        end

        % Menu selected function: Menu_4
        function Menu_4Selected(app, event)
            app.Label_2.Text = sprintf('Signal Type: %.1f, Channel: %.1f, T: %.1f, Channell: %.1f, method: %.1f, N1: %.1f, N2: %.1f, N3: %.1f, channelll: %.1f', app.signal_type, app.channel, app.T,app.channell,app.method,app.N1,app.N2,app.N3,app.channelll);
        end

        % Menu selected function: Menu_8
        function Menu_8Selected(app, event)
            app.Menu_8.Enable="off";
            app.signal_synthesis_app=signal_synthesis(app);
        end

        % Menu selected function: Menu_11
        function Menu_11Selected(app, event)
            app.Menu_11.Enable="off";
            app.conv_app=conv(app);
        end

        % Menu selected function: CH1Menu_4
        function CH1Menu_4Selected(app, event)
            app.CH1Menu_4.Enable="off";
            app.CH1Menu_5.Enable="off";
            app.CH2Menu_4.Enable="off";
            app.CH2Menu_5.Enable="off";
            app.signature_app=signature(app);
        end

        % Menu selected function: CH2Menu_4
        function CH2Menu_4Selected(app, event)
            app.CH1Menu_4.Enable="off";
            app.CH1Menu_5.Enable="off";
            app.CH2Menu_4.Enable="off";
            app.CH2Menu_5.Enable="off";
            app.signature_app=signature(app);            
        end

        % Menu selected function: CH1Menu_5
        function CH1Menu_5Selected(app, event)
            app.CH1Menu_4.Enable="off";
            app.CH1Menu_5.Enable="off";
            app.CH2Menu_4.Enable="off";
            app.CH2Menu_5.Enable="off";
            app.signature_app=signature(app);            
        end

        % Menu selected function: CH2Menu_5
        function CH2Menu_5Selected(app, event)
            app.CH1Menu_4.Enable="off";
            app.CH1Menu_5.Enable="off";
            app.CH2Menu_4.Enable="off";
            app.CH2Menu_5.Enable="off";
            app.signature_app=signature(app);            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = '信号';
            app.Menu_3 = uimenu(app.Menu);
            app.Menu_3.Text = '开始录制信号';
            app.CH1Menu = uimenu(app.Menu_3);
            app.CH1Menu.Text = 'CH1';
            app.CH2Menu = uimenu(app.Menu_3);
            app.CH2Menu.Text = 'CH2';
            app.Menu_4 = uimenu(app.Menu);
            app.Menu_4.MenuSelectedFcn = createCallbackFcn(app, @Menu_4Selected, true);
            app.Menu_4.Text = '暂停录制信号';
            app.Menu_5 = uimenu(app.Menu);
            app.Menu_5.MenuSelectedFcn = createCallbackFcn(app, @Menu_5Selected, true);
            app.Menu_5.Text = '信号发生器';
            app.Menu_6 = uimenu(app.Menu);
            app.Menu_6.Text = '暂停发生信号';
            app.Menu_2 = uimenu(app.UIFigure);
            app.Menu_2.Text = '波形';
            app.Menu_7 = uimenu(app.Menu_2);
            app.Menu_7.Text = '波形特征';
            app.Menu_9 = uimenu(app.Menu_7);
            app.Menu_9.Text = '自定义函数';
            app.CH1Menu_4 = uimenu(app.Menu_9);
            app.CH1Menu_4.MenuSelectedFcn = createCallbackFcn(app, @CH1Menu_4Selected, true);
            app.CH1Menu_4.Text = 'CH1';
            app.CH2Menu_4 = uimenu(app.Menu_9);
            app.CH2Menu_4.MenuSelectedFcn = createCallbackFcn(app, @CH2Menu_4Selected, true);
            app.CH2Menu_4.Text = 'CH2';
            app.MATLABMenu = uimenu(app.Menu_7);
            app.MATLABMenu.Text = 'MATLAB函数';
            app.CH1Menu_5 = uimenu(app.MATLABMenu);
            app.CH1Menu_5.MenuSelectedFcn = createCallbackFcn(app, @CH1Menu_5Selected, true);
            app.CH1Menu_5.Text = 'CH1';
            app.CH2Menu_5 = uimenu(app.MATLABMenu);
            app.CH2Menu_5.MenuSelectedFcn = createCallbackFcn(app, @CH2Menu_5Selected, true);
            app.CH2Menu_5.Text = 'CH2';
            app.Menu_8 = uimenu(app.Menu_2);
            app.Menu_8.MenuSelectedFcn = createCallbackFcn(app, @Menu_8Selected, true);
            app.Menu_8.Text = '信号合成';
            app.Menu_10 = uimenu(app.UIFigure);
            app.Menu_10.Text = '滤波';
            app.Menu_11 = uimenu(app.Menu_10);
            app.Menu_11.MenuSelectedFcn = createCallbackFcn(app, @Menu_11Selected, true);
            app.Menu_11.Text = '卷积';
            app.FFTMenu = uimenu(app.UIFigure);
            app.FFTMenu.Text = 'FFT';
            app.CH1Menu_6 = uimenu(app.FFTMenu);
            app.CH1Menu_6.Text = 'CH1';
            app.CH2Menu_6 = uimenu(app.FFTMenu);
            app.CH2Menu_6.Text = 'CH2';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'CH1')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'CH2')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '提示：';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '信号振幅放大';
            app.Spinner = uispinner(app.UIFigure);
            app.Spinner.Value = 10;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end